classdef appMovementPhantomX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_3                   matlab.ui.container.Panel
        EditField_5               matlab.ui.control.NumericEditField
        Label_5                   matlab.ui.control.Label
        EditField_4               matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        Label_3                   matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ArticulacionesLabel       matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        AlturaEditField           matlab.ui.control.NumericEditField
        AlturaEditFieldLabel      matlab.ui.control.Label
        VerticalEditField         matlab.ui.control.NumericEditField
        VerticalEditFieldLabel    matlab.ui.control.Label
        HorizontalEditField       matlab.ui.control.NumericEditField
        HorizontalEditFieldLabel  matlab.ui.control.Label
        PosicinefectorfinalLabel  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        Objeto1Button             matlab.ui.control.Button
        HomeButton                matlab.ui.control.Button
        Posicion3Button           matlab.ui.control.Button
        Posicion1Button           matlab.ui.control.Button
        Posicion2Button           matlab.ui.control.Button
        Slider_Servo5             matlab.ui.control.Slider
        Servo5Label               matlab.ui.control.Label
        Slider_Servo4             matlab.ui.control.Slider
        Servo4Label               matlab.ui.control.Label
        Slider_Servo3             matlab.ui.control.Slider
        Servo3Label               matlab.ui.control.Label
        Slider_Servo2             matlab.ui.control.Slider
        Servo2Label               matlab.ui.control.Label
        Slider_Servo1             matlab.ui.control.Slider
        Servo1SliderLabel         matlab.ui.control.Label
        Spinner_Servo5            matlab.ui.control.Spinner
        Spinner_Servo4            matlab.ui.control.Spinner
        Spinner_Servo3            matlab.ui.control.Spinner
        Spinner_Servo2            matlab.ui.control.Spinner
        Spinner_Servo1            matlab.ui.control.Spinner
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        valueServo1 
        valueServo2 
        valueServo3 
        valueServo4 
        valueServo5
    end
    
    methods (Access = private)
        
        function GrapRobotphantom(app)
            ws = [-24 24 -24 24 -4.5 40];
            L = [1, 10, 10, 1, 1]; 
            offset = [0, -pi/2, 0, -pi/2, 0];
            q = [app.valueServo1, app.valueServo2, app.valueServo3, app.valueServo4, app.valueServo5]*pi/180;
            limitesEjes = [-25 25; -25 25; -5 25];

            plot_options = {'workspace',ws,'scale',.5,'noa','view',[125 25], 'tilesize',2, ...
                            'ortho', 'lightpos',[2 2 10], ...
                            'floorlevel',0, 'base'};
            
            %            Theta  d   a   alpha  type mdh offset  qlim
            ParameterDH(1) = Link('revolute'   ,'alpha',      -pi/2,  'a',  0,      'd', ...
                        L(1) , 'offset',    offset(1), 'qlim', [-0 2*pi]);
            
            ParameterDH(2) = Link('revolute'   ,'alpha',      0,  'a',  L(2),      'd', ...
                        0 , 'offset',    offset(2), 'qlim', [-pi/3 pi/3]);
            
            ParameterDH(3) = Link('revolute'   ,'alpha',      0,  'a',  L(3),      'd', ...
                        0 , 'offset',    offset(3), 'qlim', [-pi/2 pi/2]);
            
            ParameterDH(4) = Link('revolute'   ,'alpha',      -pi/2,  'a',  0,      'd', ...
                        0 , 'offset',    offset(4), 'qlim', [-2*pi/3 2*pi/3]);
            
            ParameterDH(5) = Link('revolute'   ,'alpha',      0,  'a',  0,      'd', ...
                        L(4)+L(5) , 'offset',    offset(5), 'qlim', [-2*pi/3 2*pi/3]);
            
            RobotPhantomx = SerialLink(ParameterDH,'name','PhantomX','plotopt',plot_options);

                             % 
                             clf;
                             cla(app.UIAxes);
                             ax = axes();
                             RobotPhantomx.plot(q,'workspace',ws); 
                             xlim([limitesEjes(1,:)])
                             ylim([limitesEjes(2,:)])
                             zlim([limitesEjes(3,:)])
                             copyobj(ax.Children, app.UIAxes);
                             grid(app.UIAxes, 'on');
                             xlim(app.UIAxes, [limitesEjes(1,:)]); 
                             ylim(app.UIAxes, [limitesEjes(2,:)]); 
                             zlim(app.UIAxes, [limitesEjes(3,:)])
                             
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.valueServo1 = 0;
            app.valueServo2 = 0;
            app.valueServo3 = 0;
            app.valueServo4 = 0;
            app.valueServo5 = 0;
            GrapRobotphantom(app)
        end

        % Value changed function: Slider_Servo1
        function Slider_Servo1ValueChanged(app, event)

            app.valueServo1 = app.Slider_Servo1.Value;
            app.Spinner_Servo1.Value = app.valueServo1;
            GrapRobotphantom(app)
        end

        % Value changed function: Slider_Servo2
        function Slider_Servo2ValueChanged(app, event)
            app.valueServo2 = app.Slider_Servo2.Value;
            app.Spinner_Servo2.Value = app.valueServo2;
            GrapRobotphantom(app)
        end

        % Value changed function: Slider_Servo3
        function Slider_Servo3ValueChanged(app, event)
            app.valueServo3 = app.Slider_Servo3.Value;
            app.Spinner_Servo3.Value = app.valueServo3;
            GrapRobotphantom(app)
        end

        % Value changed function: Slider_Servo4
        function Slider_Servo4ValueChanged(app, event)
           app.valueServo4 = app.Slider_Servo4.Value;
            app.Spinner_Servo4.Value = app.valueServo4;
           GrapRobotphantom(app)
        end

        % Value changed function: Slider_Servo5
        function Slider_Servo5ValueChanged(app, event)
            app.valueServo5 = app.Slider_Servo5.Value;
            app.Spinner_Servo5.Value = app.valueServo5;
            GrapRobotphantom(app)
        end

        % Value changed function: Spinner_Servo1
        function Spinner_Servo1ValueChanged(app, event)
            app.valueServo1 = app.Spinner_Servo1.Value;
            app.Slider_Servo1.Value = app.valueServo1;
            GrapRobotphantom(app)
        end

        % Value changed function: Spinner_Servo2
        function Spinner_Servo2ValueChanged(app, event)
            app.valueServo2 = app.Spinner_Servo1.Value;
            app.Slider_Servo2.Value = app.valueServo2;
            GrapRobotphantom(app)
        end

        % Value changed function: Spinner_Servo3
        function Spinner_Servo3ValueChanged(app, event)
            app.valueServo3 = app.Spinner_Servo1.Value;
            app.Slider_Servo3.Value = app.valueServo3;
            GrapRobotphantom(app)       
        end

        % Value changed function: Spinner_Servo4
        function Spinner_Servo4ValueChanged(app, event)
            app.valueServo4 = app.Spinner_Servo1.Value;
            app.Slider_Servo4.Value = app.valueServo4;
            GrapRobotphantom(app)   
        end

        % Value changed function: Spinner_Servo5
        function Spinner_Servo5ValueChanged(app, event)
            app.valueServo5 = app.Spinner_Servo1.Value;
            app.Slider_Servo5.Value = app.valueServo5;
            GrapRobotphantom(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Spinner_Servo1 = uispinner(app.UIFigure);
            app.Spinner_Servo1.Limits = [0 360];
            app.Spinner_Servo1.ValueChangedFcn = createCallbackFcn(app, @Spinner_Servo1ValueChanged, true);
            app.Spinner_Servo2 = uispinner(app.UIFigure);
            app.Spinner_Servo2.Limits = [-60 60];
            app.Spinner_Servo2.ValueChangedFcn = createCallbackFcn(app, @Spinner_Servo2ValueChanged, true);
            app.Spinner_Servo3 = uispinner(app.UIFigure);
            app.Spinner_Servo3.Limits = [-90 90];
            app.Spinner_Servo3.ValueChangedFcn = createCallbackFcn(app, @Spinner_Servo3ValueChanged, true);
            app.Spinner_Servo4 = uispinner(app.UIFigure);
            app.Spinner_Servo4.Limits = [-120 120];
            app.Spinner_Servo4.ValueChangedFcn = createCallbackFcn(app, @Spinner_Servo4ValueChanged, true);
            app.Spinner_Servo5 = uispinner(app.UIFigure);
            app.Spinner_Servo5.Limits = [-120 120];
            app.Spinner_Servo5.ValueChangedFcn = createCallbackFcn(app, @Spinner_Servo5ValueChanged, true);
            app.Servo1SliderLabel = uilabel(app.UIFigure);
            app.Servo1SliderLabel.Text = 'Servo1';
            app.Slider_Servo1 = uislider(app.UIFigure);
            app.Slider_Servo1.Limits = [0 360];
            app.Slider_Servo1.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Slider_Servo1.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.Slider_Servo1.ValueChangedFcn = createCallbackFcn(app, @Slider_Servo1ValueChanged, true);
            app.Slider_Servo1.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255 260 265 270 275 280 285 290 295 300 305 310 315 320 325 330 335 340 345 350 355 360];
            app.Servo2Label = uilabel(app.UIFigure);
            app.Servo2Label.Text = 'Servo2';
            app.Slider_Servo2 = uislider(app.UIFigure);
            app.Slider_Servo2.Limits = [-60 60];
            app.Slider_Servo2.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.Slider_Servo2.ValueChangedFcn = createCallbackFcn(app, @Slider_Servo2ValueChanged, true);
            app.Slider_Servo2.MinorTicks = [-60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.Servo3Label = uilabel(app.UIFigure);
            app.Servo3Label.Text = 'Servo3';
            app.Slider_Servo3 = uislider(app.UIFigure);
            app.Slider_Servo3.Limits = [-90 90];
            app.Slider_Servo3.MajorTicks = [-90 -45 0 45 90];
            app.Slider_Servo3.ValueChangedFcn = createCallbackFcn(app, @Slider_Servo3ValueChanged, true);
            app.Slider_Servo3.MinorTicks = [-90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Servo4Label = uilabel(app.UIFigure);
            app.Servo4Label.Text = 'Servo4';
            app.Slider_Servo4 = uislider(app.UIFigure);
            app.Slider_Servo4.Limits = [-120 120];
            app.Slider_Servo4.MajorTicks = [-120 -90 -60 -30 0 30 60 90 120];
            app.Slider_Servo4.ValueChangedFcn = createCallbackFcn(app, @Slider_Servo4ValueChanged, true);
            app.Slider_Servo4.MinorTicks = [-120 -115 -110 -105 -100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120];
            app.Servo5Label = uilabel(app.UIFigure);
            app.Servo5Label.Text = 'Servo5';
            app.Slider_Servo5 = uislider(app.UIFigure);
            app.Slider_Servo5.Limits = [-120 120];
            app.Slider_Servo5.MajorTicks = [-120 -90 -60 -30 0 30 60 90 120];
            app.Slider_Servo5.ValueChangedFcn = createCallbackFcn(app, @Slider_Servo5ValueChanged, true);
            app.Slider_Servo5.MinorTicks = [-120 -115 -110 -105 -100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120];
            app.Panel = uipanel(app.UIFigure);
            app.Posicion2Button = uibutton(app.Panel, 'push');
            app.Posicion2Button.Text = 'Posicion 2';
            app.Posicion1Button = uibutton(app.Panel, 'push');
            app.Posicion1Button.Text = 'Posicion 1';
            app.Posicion3Button = uibutton(app.Panel, 'push');
            app.Posicion3Button.Text = 'Posicion 3';
            app.HomeButton = uibutton(app.Panel, 'push');
            app.HomeButton.Text = 'Home';
            app.Objeto1Button = uibutton(app.Panel, 'push');
            app.Objeto1Button.Text = 'Objeto 1';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PosicinefectorfinalLabel = uilabel(app.Panel_2);
            app.PosicinefectorfinalLabel.Text = 'Posición efector final';
            app.HorizontalEditFieldLabel = uilabel(app.Panel_2);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.Panel_2, 'numeric');
            app.VerticalEditFieldLabel = uilabel(app.Panel_2);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalEditField = uieditfield(app.Panel_2, 'numeric');
            app.AlturaEditFieldLabel = uilabel(app.Panel_2);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.ArticulacionesLabel = uilabel(app.Panel_3);
            app.ArticulacionesLabel.Text = 'Articulaciones';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '1';
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '2';
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '3';
            app.EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '4';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '5';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMovementPhantomX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end